classdef dfInspectRawData2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        MicwavFilesListBoxLabel  matlab.ui.control.Label
        MicwavFilesListBox       matlab.ui.control.ListBox
        SelectFilesButton        matlab.ui.control.Button
        FilesPathEditFieldLabel  matlab.ui.control.Label
        FilesPathEditField       matlab.ui.control.EditField
        Label                    matlab.ui.control.Label
    end


    properties (Access = private)
        defaultPath  % Where to start looking for data
        filesPath    % Where the wav files are
        curSess      % Which Subject, and run
        wavFileStr   % What the wav files are
        wavFileNames % What the wav files are called
        numFiles     % How many files are we working with
        trialNames 
        curWavFileLoc
        curWavFile
        fs
    end


    methods (Access = private)

        % Code that executes after component creation
        function setDefaultParams(app)
            app.defaultPath = 'C:\DATA\Dissociating-Role-of-Feedback-in-Voice-Motor-Control';
            
            app.FilesPathEditField.Value = app.defaultPath;
        end

        % Button pushed function: SelectFilesButton
        function SelectFiles(app, event)
            app.filesPath                = uigetdir(app.defaultPath);
            app.FilesPathEditField.Value = app.filesPath;
            
            app.wavFileStr            = dir([app.filesPath '\*micIn.wav']);
            app.wavFileNames          = {app.wavFileStr.name};
            app.wavFileNames          = sort_nat(app.wavFileNames);
            app.numFiles              = length(app.wavFileNames);

            for ii = 1:app.numFiles
                curwavFile = app.wavFileNames{ii};
                trlIdx = strfind(curwavFile, 'Trial');
                undIdx = strfind(curwavFile, '_');
                app.trialNames{ii} = curwavFile(trlIdx:undIdx-1);
            end
            
            app.curSess    = curwavFile(1:trlIdx-1);
            app.Label.Text = app.curSess;
            app.MicwavFilesListBox.Items = app.trialNames;
        end

        % Value changed function: MicwavFilesListBox
        function drawSelectedWavFileTime(app, event)
            TrialName = app.MicwavFilesListBox.Value;
            TrialIdx  = strcmp(app.trialNames, TrialName);
            
            app.curWavFileLoc = fullfile(app.filesPath, app.wavFileNames{TrialIdx});
            [app.curWavFile, app.fs] = audioread(app.curWavFileLoc);
            trialLen  = length(app.curWavFile);
            trialTime = trialLen/app.fs;
            time      = linspace(0, trialTime, trialLen);
            
            plot(app.UIAxes, time, app.curWavFile)
            app.UIAxes.Title.String = TrialName;
            app.UIAxes.XLim         = [0 5];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, ' ')
            xlabel(app.UIAxes, 'Time (s)')
            app.MicwavFilesListBoxLabel = uilabel(app.UIFigure);
            app.MicwavFilesListBoxLabel.Text = 'Mic wavFiles';
            app.MicwavFilesListBox = uilistbox(app.UIFigure);
            app.MicwavFilesListBox.Items = {};
            app.MicwavFilesListBox.ValueChangedFcn = createCallbackFcn(app, @drawSelectedWavFileTime, true);
            app.MicwavFilesListBox.Value = {};
            app.SelectFilesButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFiles, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.FilesPathEditFieldLabel = uilabel(app.UIFigure);
            app.FilesPathEditFieldLabel.Text = 'Files Path:';
            app.FilesPathEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dfInspectRawData2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @setDefaultParams)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end