classdef dfParseRawVideo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        rawVideoView                    matlab.ui.control.UIAxes
        TrialsListBoxLabel              matlab.ui.control.Label
        TrialsListBox                   matlab.ui.control.ListBox
        UpArrow                         matlab.ui.control.Button
        DnArrow                         matlab.ui.control.Button
        PlayAudioButton                 matlab.ui.control.Button
        ParticipantDropDownLabel        matlab.ui.control.Label
        ParticipantDropDown             matlab.ui.control.DropDown
        RawVideoDirEditFieldLabel       matlab.ui.control.Label
        RawVideoDirEditField            matlab.ui.control.EditField
        ChangeRawVideoDirButton         matlab.ui.control.Button
        RunDropDownLabel                matlab.ui.control.Label
        RunDropDown                     matlab.ui.control.DropDown
        LoadButton                      matlab.ui.control.Button
        StatusText                      matlab.ui.control.Label
        FrameLabel                      matlab.ui.control.Label
        NextFrameButt                   matlab.ui.control.Button
        Next10FrameButt                 matlab.ui.control.Button
        Last10FrameButt                 matlab.ui.control.Button
        LastFrameButt                   matlab.ui.control.Button
        CoderIDLabel                    matlab.ui.control.Label
        EnterIDEditFieldLabel           matlab.ui.control.Label
        EnterIDEditField                matlab.ui.control.EditField
        SaveCodedTrialsButton           matlab.ui.control.Button
        AnalyzedResultsDirEditFieldLabel  matlab.ui.control.Label
        AnalyzedResultsDirEditField     matlab.ui.control.EditField
        ChangeAnalyzedResultsDirButton  matlab.ui.control.Button
        LineLegendPanel                 matlab.ui.container.Panel
        PixelsDistLabel                 matlab.ui.control.Label
        LineLegend12                    matlab.ui.control.Image
        LineLegend34                    matlab.ui.control.Image
        PixelsDistLabel2                matlab.ui.control.Label
        LineLegend56                    matlab.ui.control.Image
        PixelsDistLabel3                matlab.ui.control.Label
    end


    properties (Access = private)
        % App specific properties
        appWinSize
        
        % Full Experimental Parameters
        CoderID
        participantList
        runList
        trialNames
        
        defaultDataPath
        defaultResultsPath
        
        % Current Data Conditions
        curDataPath
        curResultsPath
        curPart
        curRun
        curTrialName
        curTrialIdx
        curFrame
        curTime
        
        curPartDataPath
        curPartRawVidPath
        curPartRawAudFile
        curPartRawVidFile
        curPartResultsPath
        curPartResultsFile
        curPartEndoFrameFile %Final Output
        
        sRateExp
        allIdxFin
        numTrials
        expAudioM
        trialLenP
        setA
        timef0
        expAudioMf0
        presSD
        
        vidObj
        vidStr
        
        vidWidth
        vidHeight
        vidFrameR
        
        trialLenF
        setF
        
        nFrames
        
        endoAudio
        endoAfs
        
        endoAudioDN
        trialDelays
        trialRecStTimes
        videoFrameSeg
        videoSeg
        curVidSeg
        endoAudioM
        
        parseTrialT
        
        % Tracks the current state of data loaded or not
        isDataLoaded
        isExpDataLoaded
        isVidDataLoaded
        
        % Tracks the current state of buttons for selecting point locations
        selectFidPtState

        % Objects that are drawn ontop of the video frames
        fp1
        fp2
        fpLine1
        fp3
        fp4
        fpLine2
        fp5
        fp6
        fpLine3
        
        LineObj1Color
        LineObj2Color
        LineObj3Color
        
        pointAShape
        pointBShape
        
        % Logged and saved values for each trial and frame
        allOutputTables
    end

    methods (Access = private)
    
        function setDefaults(app)
            % This identifies some default experimental parameters that makes locating and loading data easy. 
            % This is a record of which participants have data worth looking at, and which runs were recorded.
            % Finally, this sets some default global parameters as the app is initialized.
            % This function is called from 'startupFcn'
        
            app.participantList = {'DRF5', 'DRF9', 'DRF12', 'DRF14', 'DRF19'};
            app.runList         = {'SFL1'};
            
            app.curFrame        = -10; % Renders frame progression inopperative until data is loaded
            app.isDataLoaded    = 0;
            app.isExpDataLoaded = 0;
            app.isVidDataLoaded = 0;
            
            app.selectFidPtState = 0;
            
            app.LineObj1Color = [55,126,184]/255; % Cerulean Blu
            app.LineObj2Color = [77,175,74]/255;  % Leaf Green
            app.LineObj3Color = [231,41,138]/255; % Bright Magenta
            
            app.pointAShape   = 'd';
            app.pointBShape   = 'o';
            
%             drawLineLegend(app, [app.LineObj1Color; app.LineObj2Color; app.LineObj3Color])
            
            app.defaultDataPath    = 'S:\SteppLab2\SPEECHDATA\Voice\Dissociating-Role-of-Feedback-in-Voice-Motor-Control';
            app.defaultResultsPath = 'C:\Users\djsmith\Documents\MATLAB\dfResults\Results';
        end
        
        function drawLineLegend(app, objColors)
        
            [numLines, ~] = size(objColors);
        
            temp = figure('Color', [0.94,0.94,0.94]);
            thisAxes = axes('Color', [0.94,0.94,0.94]);
            thisAxes.XAxis.TickLabels = {};
            thisAxes.YAxis.TickLabels = {};
            
            pos = [10 10];
        
            for i = 1:numLines
                lineSt = pos(1) - 20;
                lineSp = pos(1) + 20;
                lineH  = pos(2) - 10*i;
                
                keyLabels = [1 2] + 2*(i-1);
                curColor = objColors(i, :);
                
                hold on
                plot([lineSt lineSp], [lineH, lineH], 'Color', curColor, 'LineWidth', 3);
                plot(lineSt, lineH, 'Color', curColor, 'Marker', 'd', 'MarkerFaceColor', curColor, 'MarkerSize',8);
                plot(lineSp, lineH, 'Color', curColor, 'Marker', 'o', 'MarkerFaceColor', curColor, 'MarkerSize',8);
                
                text(lineSt-1.5, lineH-2, num2str(keyLabels(1)), 'FontSize', 12, 'FontWeight',"bold")
                text(lineSp-1.5, lineH-2, num2str(keyLabels(2)), 'FontSize', 12, 'FontWeight',"bold")
                
                rectangle('Position',[lineSt-3 lineH-3.5 (lineSp-lineSt)+6 5])

                hold off
            end
            axis([-60 90 -30 10])
        end

        function LoadRawData(app)
        %Let's go find the raw data files, and set them up to be used in the app
        % This function calls the following functions
        %-setCurrentDir, checkFile, parseTrials, switchCurTrial
        
            app.curPart = app.ParticipantDropDown.Value;
            app.curRun  = app.RunDropDown.Value;
        
            % Set up the directories for this participant/run combo
            setCurrentDir(app)
            
            % Check that files are there.
            % Also load the files
            checkFile(app)
            
            if app.isDataLoaded == 1 %If the data was loaded successfully
                % Parse the large video file into individual trial videos
                parseTrials(app)
                
                % Switch to first trial
                switchCurTrial(app, 0)
            end
        end
        
        function setCurrentDir(app)
        % setCurrentDir(app) creates the full path strings for where data
        % can be found for this app.
        % This function is called from 'checkFile'
        % This file relies on app.curPart, app.curRun, app.curDataPath, and app.curResultsPath
        
            app.curPartDataPath = fullfile(app.curDataPath, app.curPart);
            
            app.curPartRawVidPath = fullfile(app.curPartDataPath, 'rawVideo');
            app.curPartRawAudFile = fullfile(app.curPartRawVidPath, [app.curPart ' rawAudio.wav']);
            app.curPartRawVidFile = fullfile(app.curPartRawVidPath, [app.curPart ' rawVideo.avi']);
            
            app.curPartResultsPath   = fullfile(app.curResultsPath, app.curPart, app.curRun);
            app.curPartResultsFile   = fullfile(app.curPartResultsPath, [app.curPart app.curRun 'ResultsDRF.mat']);
        end
        
        function checkFile(app)
            
            app.StatusText.Text = '';
            if isfile(app.curPartResultsFile)
                expResultsStat = 1;
                app.StatusText.Text = 'Loading Experimental Results';
                loadExpResults(app)
                app.StatusText.Text = '';
                
                app.isExpDataLoaded = 1;
            else
                expResultsStat = 0;
                app.StatusText.Text = 'Could Not Locate Experimental Results. Please check Dirs';
            end
            
            if isfile(app.curPartRawVidFile) && expResultsStat == 1
                app.StatusText.Text = 'Loading Kay Pentax Audio Data';
                loadRawAudio(app)
                app.StatusText.Text = 'Loading Kay Pentax Video Data';
                loadRawVideo(app)
                app.StatusText.Text = '';
                
                app.isVidDataLoaded = 1;
            else
                app.StatusText.Text = 'Could Not Locate Video Data. Please check Dirs';
            end
            
            if app.isExpDataLoaded == 1 && app.isVidDataLoaded == 1
                app.isDataLoaded = 1;
            end
            
        end
        
        function loadExpResults(app)
            load(app.curPartResultsFile) % Returns res
            
            app.sRateExp    = res.sRate;
            app.allIdxFin   = res.allIdxFin;
            app.numTrials   = length(app.allIdxFin);
            app.expAudioM   = res.audioMinc;
            app.trialLenP   = length(app.expAudioM);  % Number of points in one trial
            app.setA        = (1:app.trialLenP) - 1;
            app.timef0      = res.timef0;
            app.expAudioMf0 = res.audioMf0TrialPert;
            app.presSD      = res.presSDsv;
            
            app.trialNames = {};
            for ii = 1:app.numTrials
                app.trialNames{ii} = ['Trial ' num2str(app.allIdxFin(ii))];
            end
            
            app.TrialsListBox.Items = app.trialNames;
            app.TrialsListBox.Value = app.trialNames(1);
        end

        function loadRawAudio(app)
            [app.endoAudio, app.endoAfs] = audioread(app.curPartRawAudFile);
        end
        
        function loadRawVideo(app)
            app.vidObj = VideoReader(app.curPartRawVidFile);
            
            app.vidWidth  = app.vidObj.Width;      % Video Width
            app.vidHeight = app.vidObj.Height;     % Video Height
            app.vidFrameR = app.vidObj.FrameRate;  % Video Framerate
            app.trialLenF = round(app.trialLenP*(app.vidFrameR/app.sRateExp)); % Num of Frames in one trial
            app.setF      = (1:app.trialLenF) - 1;
            
            app.vidStr = struct('cdata', zeros(app.vidHeight, app.vidWidth, 3, 'uint8'), 'colormap', []);
            
            itrFrames = 0;
            while hasFrame(app.vidObj)
                itrFrames = itrFrames+1;
                app.vidStr(itrFrames).cdata = readFrame(app.vidObj);                
            end

            setViewWindowParams(app)            
        end
        
        function setViewWindowParams(app)
            
            title(app.rawVideoView, []);
            xlabel(app.rawVideoView, []);
            ylabel(app.rawVideoView, []);
            app.rawVideoView.XAxis.TickLabels = {};
            app.rawVideoView.YAxis.TickLabels = {};
            
            app.rawVideoView.XLim = [0 app.vidWidth];
            app.rawVideoView.YLim = [0 app.vidHeight];            
        end
        
        function parseTrials(app)
            
            app.endoAudioDN = resample(app.endoAudio(:,1), app.sRateExp, app.endoAfs);
            
            app.allOutputTables = cell(app.numTrials, 1);

            app.trialDelays     = zeros(app.numTrials, 1);
            app.trialRecStTimes = zeros(app.numTrials, 1);
            app.videoFrameSeg   = zeros(app.numTrials, 2);
            app.videoSeg        = cell(app.numTrials, 1);
            app.endoAudioM      = zeros(app.trialLenP, app.numTrials);
            for ii = 1:app.numTrials
                app.trialDelays(ii)     = xCorrTimeLag(app, app.endoAudioDN, app.expAudioM(:,ii));
                app.trialRecStTimes(ii) = round(app.trialDelays(ii)/app.sRateExp, 2);
                app.endoAudioM(:,ii)    = app.endoAudioDN(app.setA + app.trialDelays(ii));
                
                videoFrameSt = floor(app.trialRecStTimes(ii)*app.vidFrameR);
                videoFrameSp = videoFrameSt + app.trialLenF;
                
                app.videoFrameSeg(ii, :) = [videoFrameSt videoFrameSp];                
                app.videoSeg{ii} = app.vidStr(videoFrameSt:videoFrameSp);
                app.nFrames = length(app.videoSeg{ii});
                
                % Set up the Output Table that will record Fiduciary points
                % and pixel distances
                app.allOutputTables{ii} = setTrialOutputTable(app);
            end
            app.parseTrialT = table(app.trialRecStTimes, app.videoFrameSeg(:, 1), 'VariableNames', {'Time', 'Frame'});
            
        end
        
        function pointLag = xCorrTimeLag(app, sig1, sig2)
            % xCorrTimeLag(sig1, sig2, fs) calculates the lag between two (seemingly) 
            % identical time based signals. 
            %
            % if timeLag is positive, then sig1 leads sig2. 
            % if timeLag is negative, then sig1 lags sig2.
            
            % Simple crosscorrelation between two signals
            % Finds the largest peak of the result
            [r, lags]    = xcorr(sig1, sig2);
            [~, peakInd] = max(r);
            pointLag     = lags(peakInd);
        end
        
        function setFrameCounterLabel(app)
            % Called within dispFrame
            
            counterLabel = sprintf('Frame\n%i/%i', app.curFrame, app.nFrames);
            app.FrameLabel.Text = counterLabel;
        end
        
        function dispFrame(app, thisFrame)
        % dispFrame draws the current frame to the display window and also
        % draws text which indentifies the current Parti/Run/Trial/Frame
        % This function is called by switchCurTrial, switchcurFrame and playVideo
        
            % Script reached this point because new frame or new trial
            app.curFrame = thisFrame;
            app.curTime  = app.curFrame/app.vidFrameR;

            % Updated the label of the current frame counter
            setFrameCounterLabel(app);
        
            % Draw the frame to the VideoView
            image(app.rawVideoView, app.curVidSeg(app.curFrame).cdata)
            
            % Draw trial information on to the frame
            hold(app.rawVideoView, 'on')
            curSessionTxt = sprintf('%s\n%s\n%s\nFrame %d\nT = %0.2fs', app.curPart, app.curRun, app.curTrialName, app.curFrame, app.curTime);
            text(app.rawVideoView, 610, 480, curSessionTxt,...
                 'FontSize', 18,...
                 'Color', [1 1 1])
            hold(app.rawVideoView, 'off')
            
            % The frame being displayed is being reset...recreate the fid point (objects)
            [app.fp1, app.fp2, app.fpLine1] = setFiduciaryPoints(app, app.LineObj1Color);
            [app.fp3, app.fp4, app.fpLine2] = setFiduciaryPoints(app, app.LineObj2Color);
            [app.fp5, app.fp6, app.fpLine3] = setFiduciaryPoints(app, app.LineObj3Color);
            
            identifyCurPts(app)
        end
        
        function identifyCurPts(app)
        % identifyCurPts(app) is called whenever curFrame changes or when the points being displayed change. 
        % When the frame changes, the app checks the should know the point locations
        % that were selected for that frame. 
        % Currently the points, and their lengths are stored in app.allOutputTables
        
            curTable = app.allOutputTables{app.curTrialIdx};
            
            point1XY = adaptiveCodingCorrection(app, curTable.FidPt1X, curTable.FidPt1Y, app.LineObj1Color, app.pointAShape);
            point2XY = adaptiveCodingCorrection(app, curTable.FidPt2X, curTable.FidPt2Y, app.LineObj1Color, app.pointBShape);
            point3XY = adaptiveCodingCorrection(app, curTable.FidPt3X, curTable.FidPt3Y, app.LineObj2Color, app.pointAShape);
            point4XY = adaptiveCodingCorrection(app, curTable.FidPt4X, curTable.FidPt4Y, app.LineObj2Color, app.pointBShape);
            point5XY = adaptiveCodingCorrection(app, curTable.FidPt5X, curTable.FidPt5Y, app.LineObj3Color, app.pointAShape);
            point6XY = adaptiveCodingCorrection(app, curTable.FidPt6X, curTable.FidPt6Y, app.LineObj3Color, app.pointBShape);
            
            [app.fp1, app.fp2, app.fpLine1] = moveFiduciaryPoints(app, app.fp1, app.fp2, app.fpLine1, point1XY, point2XY);
            updateOutputTableDist(app, app.fpLine1, 1)
            
            [app.fp3, app.fp4, app.fpLine2] = moveFiduciaryPoints(app, app.fp3, app.fp4, app.fpLine2, point3XY, point4XY);
            updateOutputTableDist(app, app.fpLine2, 2)
            
            [app.fp5, app.fp6, app.fpLine3] = moveFiduciaryPoints(app, app.fp5, app.fp6, app.fpLine3, point5XY, point6XY);
            updateOutputTableDist(app, app.fpLine3, 3)
            
        end
        
        function curPoint = adaptiveCodingCorrection(app, pointXCol, pointYCol, color, shape)
            
            curPoint = [pointXCol(app.curFrame) pointYCol(app.curFrame)];
            
            if app.curFrame ~=1
                lastPoint = [pointXCol(app.curFrame-1) pointYCol(app.curFrame-1)];
                
                if ~isequal(lastPoint, [0 0]) && isequal(curPoint, [0 0])
                    hold(app.rawVideoView, 'on')
                    plot(app.rawVideoView, lastPoint(1), lastPoint(2), 'Color', color, 'Marker', shape, 'MarkerFaceColor', 'none', 'MarkerSize',15);
                    hold(app.rawVideoView, 'off')
                end
            end
        
        end
        
        function [pt1Obj, pt2Obj, lineObj] = setFiduciaryPoints(app, objColor)
          
            defaultPt1XY = [0 0];
            defaultPt2XY = [0 0];
        
            hold(app.rawVideoView, 'on')
            lineObj = plot(app.rawVideoView, [defaultPt1XY(1) defaultPt2XY(1)], [defaultPt1XY(2), defaultPt2XY(2)], 'Color', objColor, 'LineWidth', 3);
            pt1Obj  = plot(app.rawVideoView, defaultPt1XY(1), defaultPt1XY(2), 'Color', objColor, 'Marker', app.pointAShape, 'MarkerFaceColor', objColor, 'MarkerSize',8);
            pt2Obj  = plot(app.rawVideoView, defaultPt2XY(1), defaultPt2XY(2), 'Color', objColor, 'Marker', app.pointBShape, 'MarkerFaceColor', objColor, 'MarkerSize',8);
            hold(app.rawVideoView, 'off')
        end
        
        function [pt1Obj, pt2Obj, lineObj] = moveFiduciaryPoints(app, pt1Obj, pt2Obj, lineObj, point1XY, point2XY)
            set(pt1Obj, 'XData', point1XY(1),...
                        'YData', point1XY(2),...
                        'Visible', 'On');
            
            set(pt2Obj, 'XData', point2XY(1),...
                        'YData', point2XY(2),...
                        'Visible', 'On');
             
            set(lineObj, 'XData', [point1XY(1) point2XY(1)],... 
                         'YData', [point1XY(2) point2XY(2)])
        end
        
        function lineDist = calcPixelDist(app, lineObj)
            XVals = lineObj.XData;
            YVals = lineObj.YData;
            
            lineDist = sqrt((diff(XVals))^2 + (diff(YVals))^2);
            lineDist = round(lineDist, 2);
        end
        
        function defaultOutputTable = setTrialOutputTable(app)
            % For each trial, create a table that is size nFrames x numOutput
            
            varNames = {'FidPt1X', 'FidPt1Y', 'FidPt2X', 'FidPt2Y', 'Dist',...
                        'FidPt3X', 'FidPt3Y', 'FidPt4X', 'FidPt4Y', 'Dist2',...
                        'FidPt5X', 'FidPt5Y', 'FidPt6X', 'FidPt6Y', 'Dist3'};
            numOutput = length(varNames);
            varTypes  = cell(1, numOutput);
            varTypes(:) = {'double'};
            
            defaultOutputTable = table('Size', [app.nFrames, numOutput],...
                                       'VariableTypes', varTypes,...
                                       'VariableNames', varNames);                             
        end
        
        function updateOutputTableDist(app, lineObj, curLine)
            
            lineDist = calcPixelDist(app, lineObj);
            switch curLine
                case 1
                    app.PixelsDistLabel.Text = [num2str(lineDist) ' Pixels'];
                    app.allOutputTables{app.curTrialIdx}.Dist(app.curFrame) = lineDist;
                case 2
                    app.PixelsDistLabel2.Text = [num2str(lineDist) ' Pixels'];
                    app.allOutputTables{app.curTrialIdx}.Dist2(app.curFrame) = lineDist;
                case 3
                    app.PixelsDistLabel3.Text = [num2str(lineDist) ' Pixels'];
                    app.allOutputTables{app.curTrialIdx}.Dist3(app.curFrame) = lineDist;
            end
        
        end
        
        function updateOutputTablePoints(app, curPoint)
            % Once a legal click has been made, decide which point was meant to be selected.
            
            % Based on the selected point, the following must happen:
            % -Save the new point to the table
            % -Redraw the given point in the rawVideoView and the connecting line
            % -Recalculate the line length from the new point
            % -Save the length in table
            % -Change the label on the app
        
            X = curPoint(1); Y = curPoint(2);
        
            switch app.selectFidPtState
                case 1 %FidPt1
                    app.allOutputTables{app.curTrialIdx}.FidPt1X(app.curFrame) = X;
                    app.allOutputTables{app.curTrialIdx}.FidPt1Y(app.curFrame) = Y;
                case 2 %FidPt2
                    app.allOutputTables{app.curTrialIdx}.FidPt2X(app.curFrame) = X;
                    app.allOutputTables{app.curTrialIdx}.FidPt2Y(app.curFrame) = Y;
                case 3 %FidPt3
                    app.allOutputTables{app.curTrialIdx}.FidPt3X(app.curFrame) = X;
                    app.allOutputTables{app.curTrialIdx}.FidPt3Y(app.curFrame) = Y;
                case 4 %FidPt4
                    app.allOutputTables{app.curTrialIdx}.FidPt4X(app.curFrame) = X;
                    app.allOutputTables{app.curTrialIdx}.FidPt4Y(app.curFrame) = Y;
                case 5 %FidPt5
                    app.allOutputTables{app.curTrialIdx}.FidPt5X(app.curFrame) = X;
                    app.allOutputTables{app.curTrialIdx}.FidPt5Y(app.curFrame) = Y;
                case 6 %FidPt6
                    app.allOutputTables{app.curTrialIdx}.FidPt6X(app.curFrame) = X;
                    app.allOutputTables{app.curTrialIdx}.FidPt6Y(app.curFrame) = Y;
            end

            identifyCurPts(app)
        end
        
        function saveCodedDataset(app)
        
            app.curPartEndoFrameFile = fullfile(app.curResultsPath, app.curPart, app.curRun, [app.curPart app.curRun 'EndoFrameMeasures' app.CoderID '.mat']);

            CodedEndoFrameDataSet = app.allOutputTables;
            
            app.StatusText.Text = 'Saving Endoscopy Frame Measurements';
            save(app.curPartEndoFrameFile, 'CodedEndoFrameDataSet')
            app.StatusText.Text = '';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            matlab.video.read.UseHardwareAcceleration('off')
             
            app.StatusText.Text = '';
            app.appWinSize = app.UIFigure.Position(3:4);
            
            % Initialize the menus/fields with experimental specific strings
            setDefaults(app)
            
            app.ParticipantDropDown.Items = app.participantList;
            app.ParticipantDropDown.Value = app.participantList{1};
            
            app.RunDropDown.Items = app.runList;
            app.RunDropDown.Value = app.runList{1};
            
            app.curDataPath     = app.defaultDataPath;
            app.curResultsPath  = app.defaultResultsPath;
            
            app.RawVideoDirEditField.Value        = app.curDataPath;
            app.ChangeRawVideoDirButton.Tag       = 'video';
            app.AnalyzedResultsDirEditField.Value = app.curResultsPath;
            app.ChangeAnalyzedResultsDirButton.Tag = 'results';
        end

        % Button pushed function: ChangeAnalyzedResultsDirButton, 
        % ChangeRawVideoDirButton
        function ChangeDir(app, event)
            buttTag = event.Source.Tag;
            
            if strcmp(buttTag, 'video')
                filePath = uigetdir(app.curDataPath);
                
                if filePath ~= 0
                    app.curDataPath                = filePath;
                    app.RawVideoDirEditField.Value = app.curDataPath;
                end
            
            elseif strcmp(buttTag, 'results')
                filePath = uigetdir(app.curResultsPath);
                
                if filePath ~= 0
                    app.curResultsPath                    = filePath;
                    app.AnalyzedResultsDirEditField.Value = app.curResultsPath;
                end
            end
        end

        % Button pushed function: LoadButton
        function CheckDataThenLoadNew(app, event)
            % The load button has been pushed. 
            if app.isDataLoaded == 1
                answer = questdlg('There a data set already open. If you load a new data set, you will lose your current coding progress. Do you want to continue working on it, or do you want to load a new one?', 'Load new data set?', 'Continue working', 'Load new data set', 'Continue working');
                if strcmp(answer, 'Load new data set') == 1
                    LoadRawData(app)
                end
            else
                LoadRawData(app)
            end
        end

        % Value changed function: TrialsListBox
        function switchCurTrial(app, event)
            app.curTrialName = app.TrialsListBox.Value;
            app.curTrialIdx  = strcmp(app.trialNames, app.curTrialName);
            
            app.curVidSeg   = app.videoSeg{app.curTrialIdx};
            app.nFrames     = length(app.curVidSeg);
            
            dispFrame(app, 1)    % Reset the viewer and display the 1st frame of this trial
        end

        % Button pushed function: UpArrow
        function ScrollUpInList(app, event)
            trialIdx = find(strcmp(app.trialNames, app.curTrialName) == 1);
            
            if trialIdx ~= 1
                newtrialIdx = trialIdx - 1;
                app.TrialsListBox.Value = app.trialNames(newtrialIdx);
                switchCurTrial(app, event)
            end
        end

        % Button pushed function: DnArrow
        function ScrollDownInList(app, event)
            trialIdx = find(strcmp(app.trialNames, app.curTrialName) == 1);
            
            if trialIdx ~= app.numTrials
                newtrialIdx = trialIdx + 1;
                app.TrialsListBox.Value = app.trialNames(newtrialIdx);
                switchCurTrial(app, event)
            end
        end

        % Button pushed function: PlayAudioButton
        function PlayVideo(app, event)
            numFrameSecs = 1/app.vidFrameR;
            t = linspace(0, 4, app.nFrames);
            curTrig = app.presSD.TrigTime(app.curTrialIdx,:);
            pertOn = (t > curTrig(1) & t < curTrig(2));
            
            for ii = 1:app.nFrames
                
                image(app.rawVideoView, app.curVidSeg(ii).cdata)
                if pertOn(ii) == 1
                    hold(app.rawVideoView, 'on')
                    plot(app.rawVideoView, 680, 20, 'Color', 'r', 'Marker', '*', 'MarkerSize',15);
                    hold(app.rawVideoView, 'off')
                end
                pause(numFrameSecs)
            end
            
            dispFrame(app, 1)   % Reset the viewer and display the 1st frame of this trial
        end

        % Button pushed function: Last10FrameButt, LastFrameButt, 
        % Next10FrameButt, NextFrameButt
        function switchCurFrame(app, event)

            whichButt = event.Source.Text;
            if strcmp(whichButt, '>')
                inc = 1;
            elseif strcmp(whichButt, '<')
                inc = -1;
            elseif strcmp(whichButt, '10 <')
                inc = -10;
            elseif strcmp(whichButt, '> 10')
                inc = 10;
            else
                inc = 0;
            end
            
            nextFrame = app.curFrame + inc;            
            if nextFrame >= 1 && nextFrame <= app.nFrames
                
                % As long as we are not outside the boundaries, show the next frame
                dispFrame(app, nextFrame) % Have the viewer display the intended trial
            end
        end

        % Key press function: UIFigure
        function KeyPress(app, event)
            key = event.Key;
            
            switch key
                case '1'
                    app.selectFidPtState = 1;
                case '2'
                    app.selectFidPtState = 2;
                case '3'
                    app.selectFidPtState = 3;
                case '4'
                    app.selectFidPtState = 4;
                case '5'
                    app.selectFidPtState = 5;
                case '6'
                    app.selectFidPtState = 6;
            end

        end

        % Key release function: UIFigure
        function KeyRelease(app, event)
            key = event.Key;
            
            switch key
                case '1'
                    app.selectFidPtState = 0;
                case '2'
                    app.selectFidPtState = 0;
                case '3'
                    app.selectFidPtState = 0;
                case '4'
                    app.selectFidPtState = 0;
                case '5'
                    app.selectFidPtState = 0;
                case '6'
                    app.selectFidPtState = 0;
            end
        end

        % Window button down function: UIFigure
        function CheckCurCursor(app, event)
            
            if app.isDataLoaded == 1
                curPointClick = app.rawVideoView.CurrentPoint;
                curPointClick = curPointClick(1,1:2);
                if (0 <= curPointClick(1)) && (app.vidWidth >= curPointClick(1)) &&... % So that it does not trigger beyond the video space
                   (0 <= curPointClick(2)) && (app.vidHeight >= curPointClick(2))
               
                   updateOutputTablePoints(app, curPointClick)
                end
            end
        end

        % Value changed function: EnterIDEditField
        function ChangeCoderID(app, event)
            value = app.EnterIDEditField.Value;
            
            app.CoderID = value;
            app.CoderIDLabel.Text = ['Coder ID: ' value];
            app.EnterIDEditField.Value = '';
        end

        % Button pushed function: SaveCodedTrialsButton
        function checkSaveConditions(app, event)
            if isempty(app.CoderID)
                msgbox('Please enter a coder ID before saving the dataset!')
            else
                saveCodedDataset(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @CheckCurCursor, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @KeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @KeyRelease, true);
            app.rawVideoView = uiaxes(app.UIFigure);
            app.rawVideoView.XTickLabel = {''; ''};
            app.rawVideoView.YTickLabel = '';
            app.TrialsListBoxLabel = uilabel(app.UIFigure);
            app.TrialsListBoxLabel.Text = 'Trials';
            app.TrialsListBox = uilistbox(app.UIFigure);
            app.TrialsListBox.Items = {' '};
            app.TrialsListBox.ValueChangedFcn = createCallbackFcn(app, @switchCurTrial, true);
            app.TrialsListBox.Value = {};
            app.UpArrow = uibutton(app.UIFigure, 'push');
            app.UpArrow.ButtonPushedFcn = createCallbackFcn(app, @ScrollUpInList, true);
            app.UpArrow.Icon = 'UpArrow.png';
            app.UpArrow.Text = '';
            app.DnArrow = uibutton(app.UIFigure, 'push');
            app.DnArrow.ButtonPushedFcn = createCallbackFcn(app, @ScrollDownInList, true);
            app.DnArrow.Icon = 'DnArrow.png';
            app.DnArrow.Text = '';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayVideo, true);
            app.PlayAudioButton.Icon = 'PlayBtn.png';
            app.PlayAudioButton.Text = 'Play Audio';
            app.ParticipantDropDownLabel = uilabel(app.UIFigure);
            app.ParticipantDropDownLabel.Text = 'Participant';
            app.ParticipantDropDown = uidropdown(app.UIFigure);
            app.ParticipantDropDown.Items = {'Participant 1', 'Participant 2', 'Participant 3', 'Participant 4'};
            app.ParticipantDropDown.Value = 'Participant 1';
            app.RawVideoDirEditFieldLabel = uilabel(app.UIFigure);
            app.RawVideoDirEditFieldLabel.Text = 'Raw Video Dir:';
            app.RawVideoDirEditField = uieditfield(app.UIFigure, 'text');
            app.ChangeRawVideoDirButton = uibutton(app.UIFigure, 'push');
            app.ChangeRawVideoDirButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeDir, true);
            app.ChangeRawVideoDirButton.Text = 'Change Dir';
            app.RunDropDownLabel = uilabel(app.UIFigure);
            app.RunDropDownLabel.Text = 'Run';
            app.RunDropDown = uidropdown(app.UIFigure);
            app.RunDropDown.Items = {'Run 1', 'Run 2'};
            app.RunDropDown.Value = 'Run 1';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @CheckDataThenLoadNew, true);
            app.LoadButton.Text = 'Load';
            app.StatusText = uilabel(app.UIFigure);
            app.StatusText.Text = '';
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = {'Frame '; '#/#'};
            app.NextFrameButt = uibutton(app.UIFigure, 'push');
            app.NextFrameButt.ButtonPushedFcn = createCallbackFcn(app, @switchCurFrame, true);
            app.NextFrameButt.Text = '>';
            app.Next10FrameButt = uibutton(app.UIFigure, 'push');
            app.Next10FrameButt.ButtonPushedFcn = createCallbackFcn(app, @switchCurFrame, true);
            app.Next10FrameButt.Text = '> 10';
            app.Last10FrameButt = uibutton(app.UIFigure, 'push');
            app.Last10FrameButt.ButtonPushedFcn = createCallbackFcn(app, @switchCurFrame, true);
            app.Last10FrameButt.Text = '10 <';
            app.LastFrameButt = uibutton(app.UIFigure, 'push');
            app.LastFrameButt.ButtonPushedFcn = createCallbackFcn(app, @switchCurFrame, true);
            app.LastFrameButt.Text = '<';
            app.CoderIDLabel = uilabel(app.UIFigure);
            app.CoderIDLabel.Text = 'Coder ID:';
            app.EnterIDEditFieldLabel = uilabel(app.UIFigure);
            app.EnterIDEditFieldLabel.Text = 'Enter ID:';
            app.EnterIDEditField = uieditfield(app.UIFigure, 'text');
            app.EnterIDEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeCoderID, true);
            app.SaveCodedTrialsButton = uibutton(app.UIFigure, 'push');
            app.SaveCodedTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @checkSaveConditions, true);
            app.SaveCodedTrialsButton.Text = 'Save Coded Trials';
            app.AnalyzedResultsDirEditFieldLabel = uilabel(app.UIFigure);
            app.AnalyzedResultsDirEditFieldLabel.Text = 'Analyzed Results Dir:';
            app.AnalyzedResultsDirEditField = uieditfield(app.UIFigure, 'text');
            app.ChangeAnalyzedResultsDirButton = uibutton(app.UIFigure, 'push');
            app.ChangeAnalyzedResultsDirButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeDir, true);
            app.ChangeAnalyzedResultsDirButton.Text = 'Change Dir';
            app.LineLegendPanel = uipanel(app.UIFigure);
            app.LineLegendPanel.Title = 'Line Legend';
            app.PixelsDistLabel = uilabel(app.LineLegendPanel);
            app.PixelsDistLabel.Text = '';
            app.LineLegend12 = uiimage(app.LineLegendPanel);
            app.LineLegend12.ImageSource = 'LineLegend_12.jpg';
            app.LineLegend34 = uiimage(app.LineLegendPanel);
            app.LineLegend34.ImageSource = 'LineLegend_34.jpg';
            app.PixelsDistLabel2 = uilabel(app.LineLegendPanel);
            app.PixelsDistLabel2.Text = '';
            app.LineLegend56 = uiimage(app.LineLegendPanel);
            app.LineLegend56.ImageSource = 'LineLegend_56.jpg';
            app.PixelsDistLabel3 = uilabel(app.LineLegendPanel);
            app.PixelsDistLabel3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dfParseRawVideo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end