classdef dfParseRawVideo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        rawVideoView  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        filePath % Description
        
        vidObj
        vidStr
        
        vidWidth
        vidHeight
        vidFrameR
        
        nFrames
    end

    methods (Access = private)
    
        function checkFile(app)
            if isfile(app.filePath)
                disp('Loading...')
                loadRawVideo(app)
            else
                error('Not a raw video file')
            end
            
        end
        
        function loadRawVideo(app)
            app.vidObj = VideoReader(app.filePath);
            
            app.vidWidth  = app.vidObj.Width;
            app.vidHeight = app.vidObj.Height;
            app.vidFrameR = app.vidObj.FrameRate;
            
            app.vidStr = struct('cdata', zeros(app.vidHeight, app.vidWidth, 3, 'uint8'), 'colormap', 'default');
            
            app.nFrames = 0;
            while hasFrame(app.vidObj)
                app.nFrames = app.nFrames+1;
                app.vidStr(app.nFrames).cdata = readFrame(app.vidObj);
                
            end          
            
            dispFrame(app)
        end
        
        function dispFrame(app)
            image(app.rawVideoView, app.vidStr(20).cdata)
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function initParams(app)
            app.filePath = fullfile('W:\Experiments\', 'Dissociating-Role-of-Feedback-in-Voice-Motor-Control', 'DRF_ENP1', 'rawVideo', 'DRF_ENP1 rawVideo.avi');
            checkFile(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.rawVideoView = uiaxes(app.UIFigure);
            app.rawVideoView.XTickLabel = '';
            app.rawVideoView.YTickLabel = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dfParseRawVideo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initParams)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end